classdef Tichphan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        InputMethodButtonGroup  matlab.ui.container.ButtonGroup
        FunctionButton          matlab.ui.control.RadioButton
        XYMethodButton          matlab.ui.control.RadioButton
        ResultLabel             matlab.ui.control.Label
        FunctionEditField       matlab.ui.control.EditField
        FunctionEditFieldLabel  matlab.ui.control.Label
        YEditField              matlab.ui.control.EditField
        YEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.EditField
        XEditFieldLabel         matlab.ui.control.Label
        MethodDropDown          matlab.ui.control.DropDown
        MethodDropDownLabel     matlab.ui.control.Label
        NEditField              matlab.ui.control.EditField
        NEditFieldLabel         matlab.ui.control.Label
        LowerEditField          matlab.ui.control.EditField
        LowerEditFieldLabel     matlab.ui.control.Label
        UpperEditField          matlab.ui.control.EditField
        UpperEditFieldLabel     matlab.ui.control.Label
        CalculateButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function InputMethodButtonGroupButtonDown(app, event)

        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            if app.XYMethodButton.Value
                X = str2double(app.XEditField.Value);
                Y = str2double(app.YEditField.Value);
            else
                equation = app.FunctionEditField.Value;
                X = linspace(str2num(app.LowerEditField.Value), str2num(app.UpperEditField.Value), 100);
                % Chuyển đổi biểu thức thành đối tượng sym
                symEquation = str2sym(equation);

                % Thay thế biến độc lập trong biểu thức
                symEquationSubs = subs(symEquation, 'x', X);

                % Tính toán giá trị của biểu thức đã thay thế
                Y = eval(symEquationSubs);
            end

            method = app.MethodDropDown.Value;
            a = str2double(app.LowerEditField.Value);
            b = str2double(app.UpperEditField.Value);
            N = str2double(app.NEditField.Value);

            if isempty(X) || isempty(Y) || isnan(a) || isnan(b) || isnan(N)
                errordlg('Invalid input. Please enter valid numeric values.');
                return;
            end

            switch method
                case 'Trapezoidal'
                    result = trapezoidalRule(X, Y, a, b, N);
                case 'Simpson13'
                    result = simpson13Rule(X, Y, a, b, N);
                case 'Simpson38'
                    result = simpson38Rule(X, Y, a, b, N);
                otherwise
                    errordlg('Invalid integration method. Please select a valid method.');
                    return;
            end
            function result = trapezoidalRule(X, Y, a, b, N)
                    h = (b - a) / N;
                    result = (h / 2) * (Y(1) + 2 * sum(Y(2:end-1)) + Y(end));
            end

            function result = simpson13Rule(X, Y, a, b, N)
                    h = (b - a) / (N - 1); 
                    result = h / 3 * (Y(1) + 4 * sum(Y(2:2:end-1)) + 2 * sum(Y(3:2:end-2)) + Y(end));
            end

            function result = simpson38Rule(X, Y, a, b, N)
                    h = (b - a) / (N - 1); 
                    result = 3 * h / 8 * (Y(1) + 3 * sum(Y(2:3:end-1)) + 3 * sum(Y(3:3:end-2)) + 2 * sum(Y(4:3:end-3)) + Y(end));
            end

            app.ResultLabel.Text = sprintf('Result: %.7f', result);
        end

        % Selection changed function: InputMethodButtonGroup
        function InputMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.InputMethodButtonGroup.SelectedObject;
            if app.XYMethodButton.Value
                app.XEditField.Visible = 'on';
                app.YEditField.Visible = 'on';
                app.FunctionEditField.Visible = 'off';
            else
                app.XEditField.Visible = 'off';
                app.YEditField.Visible = 'off';
                app.FunctionEditField.Visible = 'on';
            end 
        end

        % Button down function: InputMethodButtonGroup
        function InputMethodButtonGroupButtonDown2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.UpperEditFieldLabel = uilabel(app.UIFigure);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.UIFigure, 'text');
            app.LowerEditFieldLabel = uilabel(app.UIFigure);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.UIFigure, 'text');
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'text');
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Trapezoidal', 'Simpson 13', 'Simpson 38'};
            app.MethodDropDown.Value = 'Trapezoidal';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'text');
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.InputMethodButtonGroup = uibuttongroup(app.UIFigure);
            app.InputMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InputMethodButtonGroupSelectionChanged, true);
            app.InputMethodButtonGroup.Title = 'Input Method Button Group';
            app.InputMethodButtonGroup.ButtonDownFcn = createCallbackFcn(app, @InputMethodButtonGroupButtonDown2, true);
            app.XYMethodButton = uiradiobutton(app.InputMethodButtonGroup);
            app.XYMethodButton.Text = 'XY Method';
            app.XYMethodButton.Value = true;
            app.FunctionButton = uiradiobutton(app.InputMethodButtonGroup);
            app.FunctionButton.Text = 'Function';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tichphan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end