classdef mau < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        saiso             matlab.ui.control.EditField
        SaischophpLabel   matlab.ui.control.Label
        b                 matlab.ui.control.EditField
        bEditFieldLabel   matlab.ui.control.Label
        a                 matlab.ui.control.EditField
        aEditFieldLabel   matlab.ui.control.Label
        Run               matlab.ui.control.Button
        Nghiem            matlab.ui.control.Label
        Phuongphap        matlab.ui.container.ButtonGroup
        Chiadoi           matlab.ui.control.RadioButton
        Tieptuyen         matlab.ui.control.RadioButton
        Lap               matlab.ui.control.RadioButton
        Fx                matlab.ui.control.EditField
        NhpphngtrnhLabel  matlab.ui.control.Label
        Axes              matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Selection changed function: Phuongphap
        function PhuongphapSelectionChanged(app, event)
           
        
        end

        % Button pushed function: Run
        function RunButtonPushed(app, event)
%     Kiểm tra xem phương pháp đã được chọn chưa
    if isempty(app.Phuongphap.SelectedObject)
        % Hiển thị thông báo nếu không có phương pháp được chọn
        uialert(app.UIFigure, 'Vui lòng chọn một phương pháp!', 'Cảnh báo');
        return; % Kết thúc hàm nếu không có phương pháp nào được chọn
    end
    % Lấy giá trị từ các Edit Field
    fx = app.Fx.Value;
    A = str2double(app.a.Value);
    B = str2double(app.b.Value);
    Saiso = str2double(app.saiso.Value);

    % Lựa chọn phương pháp dựa trên Radio Button
    selectedMethod = app.Phuongphap.SelectedObject.Text;
    
    % Thực hiện tính toán và lấy kết quả nghiệm
    [nghiem, solanlap] = computeSolution(selectedMethod, fx, A, B, Saiso);
    % Hiển thị kết quả nghiệm và số lần lặp ra Label
    app.Nghiem.Text = sprintf('Nghiệm: %f\nSố lần lặp: %d', nghiem, solanlap);
%     % Vẽ đồ thị hàm f(x)
    if ~isempty(fx)
    try
        range = linspace(A, B, 100); % Định nghĩa range từ A đến B
        fx_ve = app.Fx.Value;
        f = str2func(['@(x) ', fx_ve]);
        y = zeros(1, 100); % Tạo một mảng có kích thước 1x100, ban đầu toàn bộ giá trị là 0
        for i = 1:1:100
            j = range(i);
            % Tính giá trị của fx từ 1 đến 100 và lưu vào mảng y
            y(i) = f(j);
        end
        plot(app.Axes, range, y);
        xlabel(app.Axes, 'x');
        ylabel(app.Axes, 'f(x)');
        title(app.Axes, 'Đồ thị hàm f(x)');
    catch
        disp('Lỗi: Chuỗi không phải là một hàm hợp lệ.');
    end
    else
        cla(app.Axes); % Xóa đồ thị nếu không có hàm được nhập
    end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
function [nghiem, solanlap] = computeSolution(selectedMethod, fx, a, b, saiso)   
    if(selectedMethod == "Chia doi")
        f = str2func(['@(x) ', fx]); % Chuyển đổi chuỗi thành hàm      
        nghiem = 0;
        solanlap = 0;
        while(1)
            if(abs(b - a) < saiso)
                nghiem = a;
                break;
            end
            c = (a + b) / 2;
            if(f(c) * f(a) < 0)
                b = c;
            else
                a = c;
            end
            solanlap = solanlap + 1;
        end
    elseif(selectedMethod == "Tiep tuyen")
        f = str2func(['@(x) ', fx]);
        df = @(x) (f(x + 1e-8) - f(x)) / 1e-8; % Xấp xỉ đạo hàm tại x
        x0 = a;
        x1 = b;
        solanlap = 0;
        while true
            fd0 = df(x0);
            
            if fd0 == 0
                error('Gia tri cua dao ham tai diem khoi tao bang 0. Khong the tien hanh tinh toan.');
            end  
            x1 = x0 - (f(x0) / fd0);
            if abs(x1 - x0) < saiso
                nghiem = x1;
                break;
            end
    
            x0 = x1;
            solanlap = solanlap + 1;
        end
    elseif(selectedMethod == "Lap")
        f = str2func(['@(x) ', fx]); % Chuyển đổi chuỗi thành hàm
        %solanlap = 0;
        x0 = a;
        x1 = b;
        fp = getIterationFunction(fx);
        i = 0;
        
        while (abs(x1-x0)>saiso) 
             x0=x1; 
             x1=fp(x0); 
             i=i+1; 
        end
        nghiem = x1; 
        solanlap = i; 
    
    else
        nghiem = NaN;
        solanlap = 0;
    end
end
function g = getIterationFunction(f)
    %syms x;
    fx = str2func(['@(x) ', f]);   
    % Sử dụng xấp xỉ đạo hàm bằng cách sử dụng sai phân hữu hạn (finite differences)
    h = 1e-8; % Độ dài bước xấp xỉ
    df_approx = @(x) (fx(x + h) - fx(x)) / h; % Xấp xỉ đạo hàm
    % Tạo hàm lặp g(x)
    g = @(x) x - fx(x) / df_approx(x);
end
        end

        % Value changed function: Fx
        function FxValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, 'F(x)')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            zlabel(app.Axes, 'Z')
            app.NhpphngtrnhLabel = uilabel(app.UIFigure);
            app.NhpphngtrnhLabel.Text = 'Nhập phương trình ';
            app.Fx = uieditfield(app.UIFigure, 'text');
            app.Fx.ValueChangedFcn = createCallbackFcn(app, @FxValueChanged, true);
            app.Phuongphap = uibuttongroup(app.UIFigure);
            app.Phuongphap.SelectionChangedFcn = createCallbackFcn(app, @PhuongphapSelectionChanged, true);
            app.Phuongphap.Title = 'Phuong phap';
            app.Lap = uiradiobutton(app.Phuongphap);
            app.Lap.Text = 'Lap';
            app.Lap.Value = true;
            app.Tieptuyen = uiradiobutton(app.Phuongphap);
            app.Tieptuyen.Text = 'Tiep tuyen';
            app.Chiadoi = uiradiobutton(app.Phuongphap);
            app.Chiadoi.Text = 'Chia doi';
            app.Nghiem = uilabel(app.UIFigure);
            app.Nghiem.Text = 'Nghiem & So lan lap';
            app.Run = uibutton(app.UIFigure, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Run.Text = 'Chay chuong trinh';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.a = uieditfield(app.UIFigure, 'text');
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Text = 'b';
            app.b = uieditfield(app.UIFigure, 'text');
            app.SaischophpLabel = uilabel(app.UIFigure);
            app.SaischophpLabel.Text = 'Sai số cho phép ';
            app.saiso = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mau

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end