classdef Var_final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TmnghimTab                    matlab.ui.container.Tab
        saiso                         matlab.ui.control.EditField
        SaischophpLabel               matlab.ui.control.Label
        b                             matlab.ui.control.EditField
        bEditFieldLabel               matlab.ui.control.Label
        a                             matlab.ui.control.EditField
        aEditFieldLabel               matlab.ui.control.Label
        Fx                            matlab.ui.control.EditField
        NhpphngtrnhLabel              matlab.ui.control.Label
        Run                           matlab.ui.control.Button
        Nghiem_2                      matlab.ui.control.Label
        Phuongphap_2                  matlab.ui.container.ButtonGroup
        Chiadoi                       matlab.ui.control.RadioButton
        Tieptuyen                     matlab.ui.control.RadioButton
        Lap                           matlab.ui.control.RadioButton
        Axes                          matlab.ui.control.UIAxes
        NisuyTab                      matlab.ui.container.Tab
        Phuongphap                    matlab.ui.control.DropDown
        PhuongphapDropDownLabel       matlab.ui.control.Label
        Nghiem                        matlab.ui.control.TextArea
        NghimTextAreaLabel            matlab.ui.control.Label
        Find                          matlab.ui.control.Button
        XO                            matlab.ui.control.NumericEditField
        NhpgitrnisuyEditFieldLabel    matlab.ui.control.Label
        Draw                          matlab.ui.control.Button
        Output                        matlab.ui.control.TextArea
        athcnisuyTextAreaLabel        matlab.ui.control.Label
        Y                             matlab.ui.control.EditField
        NhpdliuyEditFieldLabel        matlab.ui.control.Label
        X                             matlab.ui.control.EditField
        NhpdliuxEditFieldLabel        matlab.ui.control.Label
        UIAxes_Nsuy                   matlab.ui.control.UIAxes
        HiquyTab                      matlab.ui.container.Tab
        GiatriXEditField              matlab.ui.control.NumericEditField
        GitrdonEditFieldLabel         matlab.ui.control.Label
        ChonphuongphapDropDown        matlab.ui.control.DropDown
        ChnphngphpDropDownLabel       matlab.ui.control.Label
        NhapmangYEditField            matlab.ui.control.EditField
        NhpmngYEditFieldLabel         matlab.ui.control.Label
        NhapmangXEditField            matlab.ui.control.EditField
        NhpmngXEditFieldLabel         matlab.ui.control.Label
        TinhvaVeButton                matlab.ui.control.Button
        KetquadudoanLabel             matlab.ui.control.Label
        KetquaphuongtrinhhoiquyLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        ohmTab                        matlab.ui.container.Tab
        GhichPhngphptrungtmkhngthpdngsaisOhLabel  matlab.ui.control.Label
        nhapx                         matlab.ui.control.EditField
        XEditFieldLabel_2             matlab.ui.control.Label
        nhapy                         matlab.ui.control.EditField
        YEditFieldLabel_2             matlab.ui.control.Label
        sslist                        matlab.ui.control.ListBox
        SaisListBoxLabel              matlab.ui.control.Label
        giatri                        matlab.ui.control.NumericEditField
        GitrtnhohmEditFieldLabel      matlab.ui.control.Label
        list                          matlab.ui.control.ListBox
        PhngphpListBoxLabel           matlab.ui.control.Label
        buocnhay                      matlab.ui.control.NumericEditField
        BcnhyEditFieldLabel           matlab.ui.control.Label
        hamso                         matlab.ui.control.EditField
        HmsEditFieldLabel             matlab.ui.control.Label
        chon                          matlab.ui.container.ButtonGroup
        pphs                          matlab.ui.control.RadioButton
        ppxy                          matlab.ui.control.RadioButton
        ketqua                        matlab.ui.control.Label
        giai                          matlab.ui.control.Button
        TichphanTab                   matlab.ui.container.Tab
        YEditField                    matlab.ui.control.EditField
        YEditFieldLabel_3             matlab.ui.control.Label
        XEditField                    matlab.ui.control.EditField
        XEditFieldLabel_3             matlab.ui.control.Label
        MethodDropDown                matlab.ui.control.DropDown
        MethodDropDownLabel           matlab.ui.control.Label
        ResultLabel                   matlab.ui.control.Label
        NEditField                    matlab.ui.control.EditField
        NEditFieldLabel               matlab.ui.control.Label
        LowerEditField                matlab.ui.control.EditField
        LowerEditFieldLabel           matlab.ui.control.Label
        UpperEditField                matlab.ui.control.EditField
        UpperEditFieldLabel           matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        InputMethodButtonGroup        matlab.ui.container.ButtonGroup
        FunctionButton                matlab.ui.control.RadioButton
        XYMethodButton                matlab.ui.control.RadioButton
        FxEditField                   matlab.ui.control.EditField
        FxEditFieldLabel              matlab.ui.control.Label
        DanhschnhmTab                 matlab.ui.container.Tab
        NhmVarLabel                   matlab.ui.control.Label
        Lp21DTV2Label                 matlab.ui.control.Label
        KhoaintVinthngLabel           matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        Xemthanhvien                  matlab.ui.control.Button
        Thanhvien                     matlab.ui.container.ButtonGroup
        Son                           matlab.ui.control.RadioButton
        Nghia                         matlab.ui.control.RadioButton
        Nam                           matlab.ui.control.RadioButton
        Huy                           matlab.ui.control.RadioButton
        Duy                           matlab.ui.control.RadioButton
        Image                         matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: X
        function XValueChanged(app, event)

        end

        % Button pushed function: Find
        function FindButtonPushed(app, event)
            %Lay du lieu dau vao
            x = app.X.Value;
            x_number = strsplit(x, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
            xa = str2double(x_number); % Chuyển các phần tử của mảng chuỗi thành số

            y = app.Y.Value;
            y_number = strsplit(y, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
            ya = str2double(y_number); % Chuyển các phần tử của mảng chuỗi thành số
            
            xo = app.XO.Value;
            z = app.Phuongphap.Value;
            if(z == "Lagrange")
                dathuc = ResetLagrange(xa, ya);
                dathuc1 = char(dathuc);
                app.Output.Value = dathuc1;
                %%%%%%%%%%%%%%%%%%%%%%%%%%
                nghiem = Lagrange(xa, ya, xo);
                %nghiem1 = char(nghiem);
                app.Nghiem.Value = string(nghiem);
            else
                dathuc = ResetNewton(xa, ya);
                dathuc1 = char(dathuc);
                app.Output.Value = dathuc1;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                nghiem = NewtonInterpolation(xa, ya, xo);
                %nghiem1 = char(nghiem);
                app.Nghiem.Value = string(nghiem);
            end
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%Da thuc lagrange
            function result = ResetLagrange(xa, ya)
                n = length(xa);
                text = 0;
                syms x;
                for i = 1 : 1 : n
                    product = ya(i);
                    for j = 1 : 1 : n
                        if(i ~= j)
                            product = product * (x -xa(j))/(xa(i) - xa(j));
                        end
                    end
                    text = text + product;
                end
                result = simplify(text);
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%Da thuc Newton
            function result = ResetNewton(xa, ya)
                 n = length(xa);
                 d = ya;
                 for i = 1 : 1 : n
                     for j = 1: 1 :(i - 1)
                        d(i) = (d(j)- d(i)) / (xa(j) - xa(i));
                     end
                 end
                     syms x;
                     n = length(d);
                     text = d(n);
                     for i = (n - 1): -1 : 1
                        text = text * (x - xa(i)) + d(i); 
                     end
                 result = simplify(text);
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%Nghiem Lagrange
            function result = Lagrange(xa, ya, x)
                n = length(xa);
                sum = 0;
                for i = 1 : 1 : n
                    product = ya(i);
                    for j = 1 : 1 : n
                        if(i ~= j)
                            product = product * (x - xa(j))/(xa(i) -xa(j));
                        end
                    end
                sum = sum + product;
                end
                result = sum;
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%Nghiem Newton
            function result = NewtonInterpolation(xa, ya, x)
                n = length(xa);
                d = ya;
                for i = 1 : 1 : n
                    for j = 1 : 1 : (i - 1)
                        d(i) = (d(j)- d(i)) / (xa(j) - xa(i));
                    end
                end
                n = length(d);
                result = d(n);
                for i = (n - 1) : -1 : 1
                    result = result * (x - xa(i)) + d(i);
                end
            end
        end

        % Button pushed function: Draw
        function DrawButtonPushed(app, event)
            x = app.X.Value;
            x_number = strsplit(x, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
            xa = str2double(x_number); % Chuyển các phần tử của mảng chuỗi thành số

            y = app.Y.Value;
            y_number = strsplit(y, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
            ya = str2double(y_number); % Chuyển các phần tử của mảng chuỗi thành số
            
            %xo = app.XO.Value;
            plot(app.UIAxes_Nsuy, xa, ya);
        end

        % Value changed function: Phuongphap
        function PhuongphapValueChanged(app, event)
                
        end

        % Button pushed function: Xemthanhvien
        function XemthanhvienButtonPushed(app, event)
                            % Lấy giá trị của radio button được chọn
                selectedMember = app.Thanhvien.SelectedObject;
                
                % Kiểm tra xem radio button nào được chọn và cập nhật UITable
                if selectedMember == app.Son
                    data = {'Trịnh Hải Sơn', '21200202', 'Nhóm trưởng', 'Tab tích phân', '100%'};
                elseif selectedMember == app.Nghia
                    data = {'Trần Văn Nghĩa', '21200150', 'Thành Viên', 'Tab nghiệm', '100%'};
                elseif selectedMember == app.Nam
                    data = {'Lê Tấn Nam', '21200147', 'Thư ký', 'Tab nội suy', '100%'};
                elseif selectedMember == app.Huy
                    data = {'Lê Nhất Huy', '21200101', 'Thành viên', 'Tab đạo hàm', '100%'};
                elseif selectedMember == app.Duy
                    data = {'Bùi Quốc Duy', '21200068', 'Thành viên', 'Tab hồi quy', '100%'};
                else
                    % Nếu không có radio button nào được chọn, không cập nhật UITable
                    return;
                end
                
                % Hiển thị dữ liệu trong UITable
                app.UITable.Data = data;
        end

        % Callback function
        function TinhvaVeButtonPushed(app, event)
            
        end

        % Button pushed function: Run
        function RunButtonPushed(app, event)
            %     Kiểm tra xem phương pháp đã được chọn chưa
    if isempty(app.Phuongphap_2.SelectedObject)
        % Hiển thị thông báo nếu không có phương pháp được chọn
        uialert(app.UIFigure, 'Vui lòng chọn một phương pháp!', 'Cảnh báo');
        return; % Kết thúc hàm nếu không có phương pháp nào được chọn
    end
    % Lấy giá trị từ các Edit Field
    fx = app.Fx.Value;
    A = str2double(app.a.Value);
    B = str2double(app.b.Value);
    Saiso = str2double(app.saiso.Value);

    % Lựa chọn phương pháp dựa trên Radio Button
    selectedMethod = app.Phuongphap_2.SelectedObject.Text;
    
    % Thực hiện tính toán và lấy kết quả nghiệm
    [nghiem, solanlap] = computeSolution(selectedMethod, fx, A, B, Saiso);
    % Hiển thị kết quả nghiệm và số lần lặp ra Label
    app.Nghiem_2.Text = sprintf('Nghiệm: %f\nSố lần lặp: %d', nghiem, solanlap);
%     % Vẽ đồ thị hàm f(x)
    if ~isempty(fx)
    try
        range = linspace(A, B, 100); % Định nghĩa range từ A đến B
        fx_ve = app.Fx.Value;
        f = str2func(['@(x) ', fx_ve]);
        y = zeros(1, 100); % Tạo một mảng có kích thước 1x100, ban đầu toàn bộ giá trị là 0
        for i = 1:1:100
            j = range(i);
            % Tính giá trị của fx từ 1 đến 100 và lưu vào mảng y
            y(i) = f(j);
        end
        plot(app.Axes, range, y);
        xlabel(app.Axes, 'x');
        ylabel(app.Axes, 'f(x)');
        title(app.Axes, 'Đồ thị hàm f(x)');
    catch
        disp('Lỗi: Chuỗi không phải là một hàm hợp lệ.');
    end
    else
        cla(app.Axes); % Xóa đồ thị nếu không có hàm được nhập
    end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
function [nghiem, solanlap] = computeSolution(selectedMethod, fx, a, b, saiso)   
    if(selectedMethod == "Chia doi")
        f = str2func(['@(x) ', fx]); % Chuyển đổi chuỗi thành hàm      
        nghiem = 0;
        solanlap = 0;
        while(1)
            if(abs(b - a) < saiso)
                nghiem = a;
                break;
            end
            c = (a + b) / 2;
            if(f(c) * f(a) < 0)
                b = c;
            else
                a = c;
            end
            solanlap = solanlap + 1;
        end
    elseif(selectedMethod == "Newton")
        f = str2func(['@(x) ', fx]);
        df = @(x) (f(x + 1e-8) - f(x)) / 1e-8; % Xấp xỉ đạo hàm tại x
        x0 = a;
        x1 = b;
        solanlap = 0;
        while true
            fd0 = df(x0);
            
            if fd0 == 0
                error('Gia tri cua dao ham tai diem khoi tao bang 0. Khong the tien hanh tinh toan.');
            end  
            x1 = x0 - (f(x0) / fd0);
            if abs(x1 - x0) < saiso
                nghiem = x1;
                break;
            end
    
            x0 = x1;
            solanlap = solanlap + 1;
        end
    elseif(selectedMethod == "Lap")
        f = str2func(['@(x) ', fx]); % Chuyển đổi chuỗi thành hàm
        %solanlap = 0;
        x0 = a;
        x1 = b;
        fp = getIterationFunction(fx);
        i = 0;
        
        while (abs(x1-x0)>saiso) 
             x0=x1; 
             x1=fp(x0); 
             i=i+1; 
        end
        nghiem = x1; 
        solanlap = i; 
    
    else
        nghiem = NaN;
        solanlap = 0;
    end
end
function g = getIterationFunction(f)
    %syms x;
    fx = str2func(['@(x) ', f]);   
    % Sử dụng xấp xỉ đạo hàm bằng cách sử dụng sai phân hữu hạn (finite differences)
    h = 1e-8; % Độ dài bước xấp xỉ
    df_approx = @(x) (fx(x + h) - fx(x)) / h; % Xấp xỉ đạo hàm
    % Tạo hàm lặp g(x)
    g = @(x) x - fx(x) / df_approx(x);
end
        end

        % Button pushed function: giai
        function giaiButtonPushed(app, event)
            xa= app.nhapx.Value;
           ya= app.nhapy.Value;
           fx1= app.hamso.Value;
    
           h= app.buocnhay.Value;
           xi= app.giatri.Value;
           pp= app.list.Value;
           
           Saiso1= app.sslist.Value;
           
            
            x_number = strsplit(xa, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
            x = str2double(x_number); % Chuyển các phần tử của mảng chuỗi thành số

            
            y_number = strsplit(ya, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
            y = str2double(y_number); % Chuyển các phần tử của mảng chuỗi thành số


           if(app.chon.SelectedObject.Text== "X-Y")
           result = diff_xy(x,y,xi,pp);
           else
           fx=str2func(['@(x)' fx1]);
               result= diff_fx(xi, h, fx, pp, Saiso1);
           end
  app.ketqua.Text = sprintf('Kết quả= %f',result);
            
            
   function kq = diff_xy(x, y, gia_tri_can_tinh, phuongphap)
    % Kiểm tra nếu không đủ các điểm dữ liệu
    if length(x) ~= length(y)
        error('Độ dài của mảng x và y phải giống nhau.');
    end

    % Xác định độ lớn bước delta x
    delta_x = x(2) - x(1);
    
    % Tìm chỉ số của giá trị cần tính trong mảng x
    index = find(x == gia_tri_can_tinh);
    
    % Kiểm tra giá trị cần tính có tồn tại trong mảng x hay không
    if isempty(index)
        error('Giá trị cần tính không tồn tại trong mảng x.');
    end
    
    % Tính xấp xỉ đạo hàm theo phương pháp được chọn
    switch phuongphap
        case 'Tiến'
            % Tính xấp xỉ đạo hàm tiến
            if index < length(x)
                kq = (y(index + 1) - y(index)) / delta_x;
            else
                kq = NaN; % Trả về NaN nếu không thể tính đạo hàm tiến
            end
        case 'Lùi'
            % Tính xấp xỉ đạo hàm lùi
            if index > 1
                kq = (y(index) - y(index - 1)) / delta_x;
            else
                kq = NaN; % Trả về NaN nếu không thể tính đạo hàm lùi
            end
        case 'Trung tâm'
            % Tính xấp xỉ đạo hàm trung tâm
            if index > 1 && index < length(x)
                kq = (y(index + 1) - y(index - 1)) / (2 * delta_x);
            else
                kq = NaN; % Trả về NaN nếu không thể tính đạo hàm trung tâm
            end
        otherwise
            error('Phương pháp không hợp lệ. Hãy chọn "Tiến", "Lùi", hoặc "Trung tâm".');
    end
   end
   function df = diff_fx(x, h, f, phuongphap, Oh)
    if strcmp(phuongphap, 'Tiến')
        if strcmp(Oh, 'O(h)')
            df = (f(x + h) - f(x)) / h;
        else
            df = (-f(x + 2 * h) + 4 * f(x + h) - 3*f(x)) / (2*h);
            %df = (-(f(x )+ 2 * h) + 4 * (f(x)+ h) - 3*f(x)) / (2*h);
        end
    elseif strcmp(phuongphap, 'Lùi')
        if strcmp(Oh, 'O(h)')
            df = (f(x) - f(x - h)) / h;
        else
            df = (3 * f(x) - 4 * f(x - h) + f(x - 2 * h)) / (2 * h);
        end
    else
        if strcmp(Oh, 'O(h)')
            df=NaN;
        else
            df = (f(x + h) - f(x - h)) / (2 * h);
        end
    end
end
        end

        % Callback function
        function CalculateButtonPushed(app, event)
             
        end

        % Button pushed function: TinhvaVeButton
        function TinhvaVeButtonPushed2(app, event)
             xa = app.NhapmangXEditField.Value;
           x_number = strsplit(xa, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
           x = str2double(x_number); % Chuyển các phần tử của mảng chuỗi thành số
           %%
           ya = app.NhapmangYEditField.Value;
           y_number = strsplit(ya, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
           y = str2double(y_number); % Chuyển các phần tử của mảng chuỗi thành số
           %
           PP_Hquy = app.ChonphuongphapDropDown.Value;
           %
           giatridudoan = app.GiatriXEditField.Value;
           [hs_x, hs_y] = HquyTT(x, y, PP_Hquy, giatridudoan);
           
            function [hs_x, hs_y] = HquyTT(x, y, phuongphap, giatridudoan)
               if(phuongphap == "Tuyến tính")
                    degree = 1;
                    coefficients = polyfit(x, y, degree);
                    % Lấy các hệ số của đường thẳng hồi quy
                    slope = coefficients(1); % Hệ số góc
                    intercept = coefficients(2); % Hệ số giao intercept
                    hs_x = slope;
                    hs_y = intercept;
                    app.KetquaphuongtrinhhoiquyLabel.Text = sprintf('Phương trình hồi quy: y = %.2fx + %.2f\n', hs_x, hs_y);
                    x_values = 1:0.1:9; % Tạo dãy x để vẽ đường thẳng hồi quy
                    y_values = slope .* x_values + intercept; % Tính các giá trị y tương ứng
                    plot(app.UIAxes, x_values, y_values);
                    y_true = hs_x * giatridudoan + hs_y;
                    app.KetquadudoanLabel.Text = sprintf('Giá trị dự đoán khi x = %.2f là: %.5f', giatridudoan, y_true);
               elseif(phuongphap == "Hàm mũ")
                    log_x = log(x);
                    log_y = log(y);
                    % Thực hiện hồi quy tuyến tính trên dữ liệu đã chuyển đổi
                    coefficients = polyfit(log_x, log_y, 1); % Hồi quy tuyến tính bậc 1
                    % Lấy các hệ số của đường thẳng hồi quy
                    hs_x = coefficients(1); % Hệ số góc
                    hs_y = exp(coefficients(2)); % Hệ số a
                    app.KetquaphuongtrinhhoiquyLabel.Text = sprintf('Phương trình hồi quy theo hàm mũ: y = %.2f * x^%.2f\n', hs_x, hs_y);
                    x_values = 1:0.1:9; % Tạo dãy x để vẽ đường thẳng hồi quy
                    y_values = hs_x .* (x_values .^ hs_y); % Tính các giá trị y tương ứng
                    plot(app.UIAxes, x_values, y_values);
                    y_true = hs_x * (giatridudoan ^ hs_y);
                    app.KetquadudoanLabel.Text = sprintf('Giá trị dự đoán khi x = %.2f là: %.5f', giatridudoan, y_true);
                elseif(phuongphap == "Mũ e")
                    log_y = log(y);
                    % Thực hiện hồi quy tuyến tính trên dữ liệu đã chuyển đổi
                    coefficients = polyfit(x, log_y, 1); % Hồi quy tuyến tính bậc 1
                    % Lấy các hệ số của đường thẳng hồi quy
                    hs_x = coefficients(1); % Hệ số góc
                    hs_y = exp(coefficients(2)); % Hệ số a
                    app.KetquaphuongtrinhhoiquyLabel.Text = sprintf('Phương trình hồi quy theo dạng e mũ: y = %.2f * e^(%.2fx)\n', hs_x, hs_y);
                    x_values = 1:0.1:9; % Tạo dãy x để vẽ đường thẳng hồi quy
                    y_values = hs_x .* (exp(x_values .* hs_y)); % Tính các giá trị y tương ứng
                    plot(app.UIAxes, x_values, y_values);
                    y_true = hs_x * exp(giatridudoan * hs_y);
                    app.KetquadudoanLabel.Text = sprintf('Giá trị dự đoán khi x = %.2f là: %.5f', giatridudoan, y_true);
               else
                   hs_x = NaN; % Trả về NaN nếu không thể tính đạo hàm trung tâm
                   hs_y = NaN;
                end
             end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed2(app, event)
            ppt = app.InputMethodButtonGroup.SelectedObject.Text;
            pp = app.MethodDropDown.Value;
            fx1 = app.FxEditField.Value;
            fx = str2func(['@(x)' fx1]);
            
           xa = app.XEditField.Value;
           x_number = strsplit(xa, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
           x = str2double(x_number); % Chuyển các phần tử của mảng chuỗi thành số
           %%
           ya = app.YEditField.Value;
           y_number = strsplit(ya, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
           y = str2double(y_number); % Chuyển các phần tử của mảng chuỗi thành số
           %
            a1 = str2double(app.LowerEditField.Value);
            b1 = str2double(app.UpperEditField.Value);
            N = str2double(app.NEditField.Value);   
            
            if(ppt == "Function")
                y1 = tichphanham(fx, a1, b1, N, pp);
                app.ResultLabel.Text = sprintf('Tích phân của fx khi sử dụng phương pháp nhập hàm là: %.3f', y1);
            else
                %y = trapz(x, y);
                y = tichphanday(x, y, a1, b1, N);
                app.ResultLabel.Text = sprintf('Tích phân của fx khi sử dụng phương pháp XY là: %.3f', y);
            end
            function y1 = tichphanday(x, y, a, b, N)
                switch pp
                    case 'Trapezoidal'
                        y1 = trapezoidalRule_XY(x, y, a, b, N);
                    case 'Simpson13'
                        y1 = simpson13Rule(x, y, a, b, N);
                    case 'Simpson38'
                        y1 = simpson18Rule(x, y, a, b, N);
                    otherwise
                        errordlg('Invalid integration method. Please select a valid method.');
                        return;
                end
            end
            function result = trapezoidalRule_XY(X, Y, a, b, N)
                if length(X) ~= length(Y)
                    error('Độ dài của chuỗi X và Y không khớp.');
                end
                
                if N ~= length(X) - 1
                    error('Số lượng đoạn N không khớp với độ dài của chuỗi X.');
                end
                h = (b - a) / N; % Kích thước của mỗi đoạn con
                result = (Y(1) + Y(N+1)) / 2; % Tính giá trị đầu và cuối
                
                for i = 2:N
                    result = result + Y(i); % Cộng các giá trị Y vào kết quả
                end
                
                result = result * h; % Nhân kết quả với kích thước đoạn để tính toán tổng
            end
            function result = simpson13Rule(X, Y, a, b, N)
                if length(X) ~= length(Y)
                    error('Độ dài của chuỗi X và Y không khớp.');
                end
                
                if mod(N, 2) ~= 1
                    error('Số lượng đoạn N phải là số lẻ để áp dụng phương pháp Simpson 1/3.');
                end
                
                if N ~= length(X) - 1
                    error('Số lượng đoạn N không khớp với độ dài của chuỗi X.');
                end
                
                h = (b - a) / N; % Kích thước của mỗi đoạn con
                result = Y(1) + Y(N+1); % Tính giá trị đầu và cuối
                
                for i = 2:N
                    if mod(i, 2) == 0
                        result = result + 2 * Y(i); % Cộng các giá trị Y vào kết quả với trọng số 2 (điểm chẵn)
                    else
                        result = result + 4 * Y(i); % Cộng các giá trị Y vào kết quả với trọng số 4 (điểm lẻ)
                    end
                end
                
                result = result * h / 3; % Nhân kết quả với hệ số 1/3 và kích thước đoạn để tính toán tổng
            end
            function result = simpson18Rule(X, Y, a, b, N)
                if length(X) ~= length(Y)
                    error('Độ dài của chuỗi X và Y không khớp.');
                end
                
                if mod(N, 3) ~= 0
                    error('Số lượng đoạn N phải chia hết cho 3 để áp dụng phương pháp Simpson 1/8.');
                end
                
                if N ~= length(X) - 1
                    error('Số lượng đoạn N không khớp với độ dài của chuỗi X.');
                end
                
                h = (b - a) / N; % Kích thước của mỗi đoạn con
                result = Y(1) + Y(N+1); % Tính giá trị đầu và cuối
                
                for i = 2:N
                    if mod(i, 3) == 0
                        result = result + 2 * Y(i); % Cộng các giá trị Y vào kết quả với trọng số 2 (chia hết cho 3)
                    else
                        result = result + 3 * Y(i); % Cộng các giá trị Y vào kết quả với trọng số 3 (không chia hết cho 3)
                    end
                end
                
                result = result * 3 * h / 8; % Nhân kết quả với hệ số 3/8 và kích thước đoạn để tính toán tổng
            end

            function y = tichphanham(fx, a1, b1, N, pp)
                switch pp
                    case 'Trapezoidal'
                        y = tichphanhinhthang(fx,a1,b1,N);
                    case 'Simpson13'
                        y = tichphanSimpson13(fx,a1,b1,N);
                    case 'Simpson38'
                        y = tichphanSimpson18(fx, a1, b1, N);
                    otherwise
                        errordlg('Invalid integration method. Please select a valid method.');
                        return;
                end
            end
            function y = tichphanhinhthang(fx,a,b,N)
                tong = fx(a) + fx(b);
                h = (b - a) / N;
                for i = 1 : 1 : N - 1
                tong = tong + 2 * fx(a + i*h);
                end
                y = (h / 2) * tong;
            end
            function y = tichphanSimpson13(fx, a, b, N)
                tong = fx(a) + fx(b);
                h = (b - a) / N;            
                for i = 1:N-1
                    x = a + i * h;
                    if mod(i, 2) == 0
                        tong = tong + 2 * fx(x);
                    else
                        tong = tong + 4 * fx(x);
                    end
                end           
                y = (h / 3) * tong;
            end
            function y = tichphanSimpson18(fx, a, b, N)
                tong = fx(a) + fx(b);
                h = (b - a) / N;
                
                for i = 1:N-1
                    x = a + i * h;
                    if mod(i, 3) == 0
                        tong = tong + 2 * fx(x);
                    else
                        tong = tong + 3 * fx(x);
                    end
                end
                
                y = (3 * h / 8) * tong;
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TmnghimTab = uitab(app.TabGroup);
            app.TmnghimTab.Title = 'Tìm nghiệm';
            app.Axes = uiaxes(app.TmnghimTab);
            title(app.Axes, 'F(x)')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            zlabel(app.Axes, 'Z')
            app.Phuongphap_2 = uibuttongroup(app.TmnghimTab);
            app.Phuongphap_2.Title = 'Phuong phap';
            app.Lap = uiradiobutton(app.Phuongphap_2);
            app.Lap.Text = 'Lap';
            app.Lap.Value = true;
            app.Tieptuyen = uiradiobutton(app.Phuongphap_2);
            app.Tieptuyen.Text = 'Newton';
            app.Chiadoi = uiradiobutton(app.Phuongphap_2);
            app.Chiadoi.Text = 'Chia doi';
            app.Nghiem_2 = uilabel(app.TmnghimTab);
            app.Nghiem_2.Text = 'Nghiem & So lan lap';
            app.Run = uibutton(app.TmnghimTab, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Run.Text = 'Chay chuong trinh';
            app.NhpphngtrnhLabel = uilabel(app.TmnghimTab);
            app.NhpphngtrnhLabel.Text = 'Nhập phương trình ';
            app.Fx = uieditfield(app.TmnghimTab, 'text');
            app.Fx.Value = '3 * x^3 - 8 * x^2 - 20*x + 16';
            app.aEditFieldLabel = uilabel(app.TmnghimTab);
            app.aEditFieldLabel.Text = 'a';
            app.a = uieditfield(app.TmnghimTab, 'text');
            app.a.Value = '0.2';
            app.bEditFieldLabel = uilabel(app.TmnghimTab);
            app.bEditFieldLabel.Text = 'b';
            app.b = uieditfield(app.TmnghimTab, 'text');
            app.b.Value = '1';
            app.SaischophpLabel = uilabel(app.TmnghimTab);
            app.SaischophpLabel.Text = 'Sai số cho phép ';
            app.saiso = uieditfield(app.TmnghimTab, 'text');
            app.saiso.Value = '0.005';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes_Nsuy = uiaxes(app.NisuyTab);
            title(app.UIAxes_Nsuy, 'Hàm nội suy')
            xlabel(app.UIAxes_Nsuy, 'X')
            ylabel(app.UIAxes_Nsuy, 'Y')
            zlabel(app.UIAxes_Nsuy, 'Z')
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.X = uieditfield(app.NisuyTab, 'text');
            app.X.ValueChangedFcn = createCallbackFcn(app, @XValueChanged, true);
            app.X.Value = '0.1,0.2,0.3,0.4';
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.Y = uieditfield(app.NisuyTab, 'text');
            app.Y.Value = '0.09983,0.19867,0.29552,0.38942';
            app.athcnisuyTextAreaLabel = uilabel(app.NisuyTab);
            app.athcnisuyTextAreaLabel.Text = 'Đa thức nội suy';
            app.Output = uitextarea(app.NisuyTab);
            app.Draw = uibutton(app.NisuyTab, 'push');
            app.Draw.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.Draw.Text = 'Vẽ đồ thị';
            app.NhpgitrnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrnisuyEditFieldLabel.Text = 'Nhập giá trị nội suy';
            app.XO = uieditfield(app.NisuyTab, 'numeric');
            app.XO.Value = 0.14;
            app.Find = uibutton(app.NisuyTab, 'push');
            app.Find.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.Find.Text = {'Tìm nghiệm và ĐTNS'; ''};
            app.NghimTextAreaLabel = uilabel(app.NisuyTab);
            app.NghimTextAreaLabel.Text = 'Nghiệm';
            app.Nghiem = uitextarea(app.NisuyTab);
            app.PhuongphapDropDownLabel = uilabel(app.NisuyTab);
            app.PhuongphapDropDownLabel.Text = 'Phuong phap';
            app.Phuongphap = uidropdown(app.NisuyTab);
            app.Phuongphap.Items = {'Lagrange', 'Newton', ''};
            app.Phuongphap.ValueChangedFcn = createCallbackFcn(app, @PhuongphapValueChanged, true);
            app.Phuongphap.Value = 'Lagrange';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes = uiaxes(app.HiquyTab);
            title(app.UIAxes, 'Đồ thị hàm hồi quy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.KetquaphuongtrinhhoiquyLabel = uilabel(app.HiquyTab);
            app.KetquaphuongtrinhhoiquyLabel.Text = 'Kết quả phương trình hồi quy';
            app.KetquadudoanLabel = uilabel(app.HiquyTab);
            app.KetquadudoanLabel.Text = 'Kết quả dự đoán';
            app.TinhvaVeButton = uibutton(app.HiquyTab, 'push');
            app.TinhvaVeButton.ButtonPushedFcn = createCallbackFcn(app, @TinhvaVeButtonPushed2, true);
            app.TinhvaVeButton.Text = 'Run';
            app.NhpmngXEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpmngXEditFieldLabel.Text = 'Nhập mảng X';
            app.NhapmangXEditField = uieditfield(app.HiquyTab, 'text');
            app.NhapmangXEditField.Value = '1,2,3,4,5,6,7,8,9';
            app.NhpmngYEditFieldLabel = uilabel(app.HiquyTab);
            app.NhpmngYEditFieldLabel.Text = 'Nhập mảng Y';
            app.NhapmangYEditField = uieditfield(app.HiquyTab, 'text');
            app.NhapmangYEditField.Value = '1,1.5,2,3,4,5,8,10,13';
            app.ChnphngphpDropDownLabel = uilabel(app.HiquyTab);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.ChonphuongphapDropDown = uidropdown(app.HiquyTab);
            app.ChonphuongphapDropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Mũ e'};
            app.ChonphuongphapDropDown.Value = 'Tuyến tính';
            app.GitrdonEditFieldLabel = uilabel(app.HiquyTab);
            app.GitrdonEditFieldLabel.Text = 'Giá trị dự đoán';
            app.GiatriXEditField = uieditfield(app.HiquyTab, 'numeric');
            app.GiatriXEditField.Value = 12;
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.giai = uibutton(app.ohmTab, 'push');
            app.giai.ButtonPushedFcn = createCallbackFcn(app, @giaiButtonPushed, true);
            app.giai.Text = 'Giải';
            app.ketqua = uilabel(app.ohmTab);
            app.ketqua.Text = 'Kết quả:';
            app.chon = uibuttongroup(app.ohmTab);
            app.chon.Title = 'Chọn';
            app.ppxy = uiradiobutton(app.chon);
            app.ppxy.Text = 'X-Y';
            app.ppxy.Value = true;
            app.pphs = uiradiobutton(app.chon);
            app.pphs.Text = 'Hàm số';
            app.HmsEditFieldLabel = uilabel(app.ohmTab);
            app.HmsEditFieldLabel.Text = 'Hàm số';
            app.hamso = uieditfield(app.ohmTab, 'text');
            app.hamso.Value = 'x^4 + 2*x^2';
            app.BcnhyEditFieldLabel = uilabel(app.ohmTab);
            app.BcnhyEditFieldLabel.Text = 'Bước nhảy';
            app.buocnhay = uieditfield(app.ohmTab, 'numeric');
            app.buocnhay.Value = 0.08;
            app.PhngphpListBoxLabel = uilabel(app.ohmTab);
            app.PhngphpListBoxLabel.Text = 'Phương pháp';
            app.list = uilistbox(app.ohmTab);
            app.list.Items = {'Tiến', 'Lùi', 'Trung tâm'};
            app.list.Value = 'Tiến';
            app.GitrtnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.GitrtnhohmEditFieldLabel.Text = 'Giá trị tính đạo hàm';
            app.giatri = uieditfield(app.ohmTab, 'numeric');
            app.giatri.Value = 2;
            app.SaisListBoxLabel = uilabel(app.ohmTab);
            app.SaisListBoxLabel.Text = 'Sai số';
            app.sslist = uilistbox(app.ohmTab);
            app.sslist.Items = {'O(h)', 'O(h^2)'};
            app.sslist.Value = 'O(h)';
            app.YEditFieldLabel_2 = uilabel(app.ohmTab);
            app.YEditFieldLabel_2.Text = 'Y';
            app.nhapy = uieditfield(app.ohmTab, 'text');
            app.nhapy.Value = '3.00,3.88,4.95,6.24,7.76,9.56,11.67,14.13,16.98,20.25,24.00,28.27,33.11,38.56,44.70,51.56,59.22,67.72,77.15,87.55,99.00';
            app.XEditFieldLabel_2 = uilabel(app.ohmTab);
            app.XEditFieldLabel_2.Text = 'X';
            app.nhapx = uieditfield(app.ohmTab, 'text');
            app.nhapx.Value = '1.00,1.10,1.20,1.30,1.40,1.50,1.60,1.70,1.80,1.90,2.00,2.10,2.20,2.30,2.40,2.50,2.60,2.70,2.80,2.90,3.00';
            app.GhichPhngphptrungtmkhngthpdngsaisOhLabel = uilabel(app.ohmTab);
            app.GhichPhngphptrungtmkhngthpdngsaisOhLabel.Text = 'Ghi chú: Phương pháp trung tâm không thế áp dụng sai số O(h) ';
            app.TichphanTab = uitab(app.TabGroup);
            app.TichphanTab.Title = 'Tích phân';
            app.FxEditFieldLabel = uilabel(app.TichphanTab);
            app.FxEditFieldLabel.Text = 'F(x)';
            app.FxEditField = uieditfield(app.TichphanTab, 'text');
            app.FxEditField.Value = '0.2+25*x-200*x.^2+675*x.^3-900*x.^4+400*x.^5';
            app.InputMethodButtonGroup = uibuttongroup(app.TichphanTab);
            app.InputMethodButtonGroup.Title = 'Input Method Button Group';
            app.XYMethodButton = uiradiobutton(app.InputMethodButtonGroup);
            app.XYMethodButton.Text = 'XY Method';
            app.XYMethodButton.Value = true;
            app.FunctionButton = uiradiobutton(app.InputMethodButtonGroup);
            app.FunctionButton.Text = 'Function';
            app.CalculateButton = uibutton(app.TichphanTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed2, true);
            app.CalculateButton.Text = 'Calculate';
            app.UpperEditFieldLabel = uilabel(app.TichphanTab);
            app.UpperEditFieldLabel.Text = 'Upper';
            app.UpperEditField = uieditfield(app.TichphanTab, 'text');
            app.UpperEditField.Value = '0.8';
            app.LowerEditFieldLabel = uilabel(app.TichphanTab);
            app.LowerEditFieldLabel.Text = 'Lower';
            app.LowerEditField = uieditfield(app.TichphanTab, 'text');
            app.LowerEditField.Value = '0';
            app.NEditFieldLabel = uilabel(app.TichphanTab);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.TichphanTab, 'text');
            app.NEditField.Value = '10';
            app.ResultLabel = uilabel(app.TichphanTab);
            app.ResultLabel.Text = 'Result';
            app.MethodDropDownLabel = uilabel(app.TichphanTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.TichphanTab);
            app.MethodDropDown.Items = {'Trapezoidal', 'Simpson13', 'Simpson38'};
            app.MethodDropDown.Value = 'Trapezoidal';
            app.XEditFieldLabel_3 = uilabel(app.TichphanTab);
            app.XEditFieldLabel_3.Text = 'X';
            app.XEditField = uieditfield(app.TichphanTab, 'text');
            app.XEditField.Value = '0,0.12,0.22,0.32,0.36,0.4,0.44,0.54,0.64,0.7,0.8';
            app.YEditFieldLabel_3 = uilabel(app.TichphanTab);
            app.YEditFieldLabel_3.Text = 'Y';
            app.YEditField = uieditfield(app.TichphanTab, 'text');
            app.YEditField.Value = '0.200,1.309,1.305,1.743,2.074,2.456,2.843,3.507,3.181,2.363,0.232';
            app.DanhschnhmTab = uitab(app.TabGroup);
            app.DanhschnhmTab.Title = 'Danh sách nhóm';
            app.Image = uiimage(app.DanhschnhmTab);
            app.Image.ImageSource = 'th.jpg';
            app.Thanhvien = uibuttongroup(app.DanhschnhmTab);
            app.Thanhvien.Title = 'Thành viên';
            app.Duy = uiradiobutton(app.Thanhvien);
            app.Duy.Text = 'Bùi Quốc Duy';
            app.Duy.Value = true;
            app.Huy = uiradiobutton(app.Thanhvien);
            app.Huy.Text = 'Lê Nhất Huy';
            app.Nam = uiradiobutton(app.Thanhvien);
            app.Nam.Text = 'Lê Tấn Nam';
            app.Nghia = uiradiobutton(app.Thanhvien);
            app.Nghia.Text = 'Trần Văn Nghĩa';
            app.Son = uiradiobutton(app.Thanhvien);
            app.Son.Text = 'Trịnh Hải Sơn';
            app.Xemthanhvien = uibutton(app.DanhschnhmTab, 'push');
            app.Xemthanhvien.ButtonPushedFcn = createCallbackFcn(app, @XemthanhvienButtonPushed, true);
            app.Xemthanhvien.Text = 'Xem thành viên';
            app.UITable = uitable(app.DanhschnhmTab);
            app.UITable.ColumnName = {'Họ và tên'; 'MSSV'; 'Chức vụ'; 'Nhiệm vụ'; 'Mức độ hoàn thành'};
            app.UITable.RowName = {};
            app.KhoaintVinthngLabel = uilabel(app.DanhschnhmTab);
            app.KhoaintVinthngLabel.Text = 'Khoa Điện tử - Viễn thông';
            app.Lp21DTV2Label = uilabel(app.DanhschnhmTab);
            app.Lp21DTV2Label.Text = 'Lớp: 21DTV2';
            app.NhmVarLabel = uilabel(app.DanhschnhmTab);
            app.NhmVarLabel.Text = 'Nhóm: Var';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Var_final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end