classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        chon                      matlab.ui.container.ButtonGroup
        pphs                      matlab.ui.control.RadioButton
        ppxy                      matlab.ui.control.RadioButton
        nhapx                     matlab.ui.control.EditField
        XEditFieldLabel           matlab.ui.control.Label
        nhapy                     matlab.ui.control.EditField
        YEditFieldLabel           matlab.ui.control.Label
        sslist                    matlab.ui.control.ListBox
        SaisListBoxLabel          matlab.ui.control.Label
        ketqua                    matlab.ui.control.Label
        giai                      matlab.ui.control.Button
        giatri                    matlab.ui.control.NumericEditField
        GitrtnhohmEditFieldLabel  matlab.ui.control.Label
        list                      matlab.ui.control.ListBox
        PhngphpListBoxLabel       matlab.ui.control.Label
        buocnhay                  matlab.ui.control.NumericEditField
        BcnhyEditFieldLabel       matlab.ui.control.Label
        hamso                     matlab.ui.control.EditField
        HmsEditFieldLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: giai
        function giaiButtonPushed(app, event)
           xa= app.nhapx.Value;
           ya= app.nhapy.Value;
           fx1= app.hamso.Value;
    
           h= app.buocnhay.Value;
           xi= app.giatri.Value;
           pp= app.list.Value;
           saiso= app.sslist.Value;
           
            
            x_number = strsplit(xa, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
            x = str2double(x_number); % Chuyển các phần tử của mảng chuỗi thành số

            
            y_number = strsplit(ya, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
            y = str2double(y_number); % Chuyển các phần tử của mảng chuỗi thành số


           if(app.chon.SelectedObject.Text== "X-Y")
           result = diff_xy(x,y,xi,pp);
           else
           fx=str2func(['@(x)' fx1]);
               result= diff_fx(xi, h, fx, pp, saiso);
           end
  app.ketqua.Text = sprintf('Kết quả= %f',result);
            
            
   function kq = diff_xy(x, y, gia_tri_can_tinh, phuongphap)
    % Kiểm tra nếu không đủ các điểm dữ liệu
    if length(x) ~= length(y)
        error('Độ dài của mảng x và y phải giống nhau.');
    end

    % Xác định độ lớn bước delta x
    delta_x = x(2) - x(1);
    
    % Tìm chỉ số của giá trị cần tính trong mảng x
    index = find(x == gia_tri_can_tinh);
    
    % Kiểm tra giá trị cần tính có tồn tại trong mảng x hay không
    if isempty(index)
        error('Giá trị cần tính không tồn tại trong mảng x.');
    end
    
    % Tính xấp xỉ đạo hàm theo phương pháp được chọn
    switch phuongphap
        case 'Tiến'
            % Tính xấp xỉ đạo hàm tiến
            if index < length(x)
                kq = (y(index + 1) - y(index)) / delta_x;
            else
                kq = NaN; % Trả về NaN nếu không thể tính đạo hàm tiến
            end
        case 'Lùi'
            % Tính xấp xỉ đạo hàm lùi
            if index > 1
                kq = (y(index) - y(index - 1)) / delta_x;
            else
                kq = NaN; % Trả về NaN nếu không thể tính đạo hàm lùi
            end
        case 'Trung tâm'
            % Tính xấp xỉ đạo hàm trung tâm
            if index > 1 && index < length(x)
                kq = (y(index + 1) - y(index - 1)) / (2 * delta_x);
            else
                kq = NaN; % Trả về NaN nếu không thể tính đạo hàm trung tâm
            end
        otherwise
            error('Phương pháp không hợp lệ. Hãy chọn "Tiến", "Lùi", hoặc "Trung tâm".');
    end
   end
   function df = diff_fx(x, h, f, phuongphap, Oh)
    if strcmp(phuongphap, 'Tiến')
        if strcmp(Oh, 'O(h)')
            df = (f(x + h) - f(x)) / h;
        else
            df = (-f(x + 2 * h) + 4 * f(x + h) - 3*f(x)) / (2*h);
            %df = (-(f(x )+ 2 * h) + 4 * (f(x)+ h) - 3*f(x)) / (2*h);
        end
    elseif strcmp(phuongphap, 'Lùi')
        if strcmp(Oh, 'O(h)')
            df = (f(x) - f(x - h)) / h;
        else
            df = (3 * f(x) - 4 * f(x - h) + f(x - 2 * h)) / (2 * h);
        end
    else
        if strcmp(Oh, 'O(h)')
            df=0;
        else
            df = (f(x + h) - f(x - h)) / (2 * h);
        end
    end
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HmsEditFieldLabel = uilabel(app.UIFigure);
            app.HmsEditFieldLabel.Text = 'Hàm số';
            app.hamso = uieditfield(app.UIFigure, 'text');
            app.BcnhyEditFieldLabel = uilabel(app.UIFigure);
            app.BcnhyEditFieldLabel.Text = 'Bước nhảy';
            app.buocnhay = uieditfield(app.UIFigure, 'numeric');
            app.PhngphpListBoxLabel = uilabel(app.UIFigure);
            app.PhngphpListBoxLabel.Text = 'Phương pháp';
            app.list = uilistbox(app.UIFigure);
            app.list.Items = {'Tiến', 'Lùi', 'Trung tâm'};
            app.list.Value = 'Tiến';
            app.GitrtnhohmEditFieldLabel = uilabel(app.UIFigure);
            app.GitrtnhohmEditFieldLabel.Text = 'Giá trị tính đạo hàm';
            app.giatri = uieditfield(app.UIFigure, 'numeric');
            app.giai = uibutton(app.UIFigure, 'push');
            app.giai.ButtonPushedFcn = createCallbackFcn(app, @giaiButtonPushed, true);
            app.giai.Text = 'Giải';
            app.ketqua = uilabel(app.UIFigure);
            app.ketqua.Text = 'Kết quả:';
            app.SaisListBoxLabel = uilabel(app.UIFigure);
            app.SaisListBoxLabel.Text = 'Sai số';
            app.sslist = uilistbox(app.UIFigure);
            app.sslist.Items = {'O(h)', 'O(h^2)'};
            app.sslist.Value = 'O(h)';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.nhapy = uieditfield(app.UIFigure, 'text');
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.nhapx = uieditfield(app.UIFigure, 'text');
            app.chon = uibuttongroup(app.UIFigure);
            app.chon.Title = 'Chọn';
            app.ppxy = uiradiobutton(app.chon);
            app.ppxy.Text = 'X-Y';
            app.ppxy.Value = true;
            app.pphs = uiradiobutton(app.chon);
            app.pphs.Text = 'Hàm số';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end