classdef giaodienhoiquy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        xLabel                        matlab.ui.control.Label
        KQptrA1                       matlab.ui.control.Label
        yLabel                        matlab.ui.control.Label
        daucongLabel                  matlab.ui.control.Label
        TinhvaVeButton                matlab.ui.control.Button
        KetquadudoanLabel             matlab.ui.control.Label
        KQdudoan                      matlab.ui.control.Label
        GiatriXEditField              matlab.ui.control.NumericEditField
        GitrdonEditFieldLabel         matlab.ui.control.Label
        KQptrA0                       matlab.ui.control.Label
        KetquaphuongtrinhhoiquyLabel  matlab.ui.control.Label
        ChonphuongphapDropDown        matlab.ui.control.DropDown
        ChnphngphpDropDownLabel       matlab.ui.control.Label
        NhapmangYEditField            matlab.ui.control.EditField
        NhpmngYEditFieldLabel         matlab.ui.control.Label
        NhapmangXEditField            matlab.ui.control.EditField
        NhpmngXEditFieldLabel         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TinhvaVeButton
        function TinhvaVeButtonPushed(app, event)
            pp = app.ChonphuongphapDropDown.Value;
            if(pp == "Tuyến tính")

                mangX = str2num(app.NhapmangXEditField.Value);
                mangY = str2num(app.NhapmangYEditField.Value);
                giatriX = (app.GiatriXEditField.Value);
                a1 = 0;
                a0 = 0;
                r2 = 0;
                ketquadudoan = 0;
                n = length(mangX);
                sumX = 0;
                sumY = 0;
                sumXY = 0;
                sumX2 = 0;
                st = 0;
                sr = 0;
                sumX = sum(mangX);
                sumY = sum(mangY);
                for i =1:n
                    sumXY = sumXY + mangX(i)*mangY(i);
                    sumX2 = sumX2 + mangX(i)*mangX(i);
                end
                xm = sumX/n;
                ym = sumY/n;
                a1 = (n*sumXY - sumX*sumY)/(n*sumX2 - sumX*sumX);
                a0 = ym - a1*xm;

                for i =1:n
                    st = st +(mangY(1)-ym)^2;
                    sr = sr +(mangY(i) -a1*mangX(i)-a0)^2;
                end
                r2 = (st-sr)/st;
                ketquadudoan = a0+a1*giatriX
                app.KQptrA0.Text = num2str(a0);
                app.KQptrA1.Text = num2str(a1);
                app.KQdudoan.Text = num2str(ketquadudoan)
                plot(app.UIAxes, mangX, mangY);
            elseif (pp == "Hàm mũ")

                mangX = str2num(app.NhapmangXEditField.Value);
                mangY = str2num(app.NhapmangYEditField.Value);
                giatriX = (app.GiatriXEditField.Value);
                a1 = 0;
                a0 = 0;
                r2 = 0;
                ketquadudoan = 0;
                n = length(mangX);
                sumX = 0;
                sumY = 0;
                sumXY = 0;
                sumX2 = 0;
                sumX = sum(mangX);
                sumY = sum(mangY);
                for i =1:n
                    sumXY = sumXY + mangX(i)*mangY(i);
                    sumX2 = sumX2 + mangX(i)*mangX(i);
                end
                A = [sumX2 sumX; sumX  n];
                B = [sumXY, sumY];
                Z = A.\B;
                a0 = Z(1);
                a1 = Z(2);
                ketquadudoan = a0+giatriX^a1;
                app.KQptrA0.Text = num2str(a0);
                app.KQptrA1.Text = num2str(a1);
                app.KQdudoan.Text = num2str(ketquadudoan)
                plot(app.UIAxes, mangX, mangY);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Đồ thị hàm hồi quy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpmngXEditFieldLabel = uilabel(app.UIFigure);
            app.NhpmngXEditFieldLabel.Text = 'Nhập mảng X';
            app.NhapmangXEditField = uieditfield(app.UIFigure, 'text');
            app.NhapmangXEditField.Value = '[1,2,3,4,5,6,7,8,9]';
            app.NhpmngYEditFieldLabel = uilabel(app.UIFigure);
            app.NhpmngYEditFieldLabel.Text = 'Nhập mảng Y';
            app.NhapmangYEditField = uieditfield(app.UIFigure, 'text');
            app.NhapmangYEditField.Value = '[1,1.5,2,3,4,5,8,10,13]';
            app.ChnphngphpDropDownLabel = uilabel(app.UIFigure);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.ChonphuongphapDropDown = uidropdown(app.UIFigure);
            app.ChonphuongphapDropDown.Items = {'Tuyến tính', 'Hàm mũ', 'Logarit'};
            app.ChonphuongphapDropDown.Value = 'Tuyến tính';
            app.KetquaphuongtrinhhoiquyLabel = uilabel(app.UIFigure);
            app.KetquaphuongtrinhhoiquyLabel.Text = 'Kết quả phương trình hồi quy';
            app.KQptrA0 = uilabel(app.UIFigure);
            app.KQptrA0.Text = '0';
            app.GitrdonEditFieldLabel = uilabel(app.UIFigure);
            app.GitrdonEditFieldLabel.Text = 'Giá trị dự đoán';
            app.GiatriXEditField = uieditfield(app.UIFigure, 'numeric');
            app.GiatriXEditField.Value = 12;
            app.KQdudoan = uilabel(app.UIFigure);
            app.KQdudoan.Text = 'Label3';
            app.KetquadudoanLabel = uilabel(app.UIFigure);
            app.KetquadudoanLabel.Text = 'Kết quả dự đoán';
            app.TinhvaVeButton = uibutton(app.UIFigure, 'push');
            app.TinhvaVeButton.ButtonPushedFcn = createCallbackFcn(app, @TinhvaVeButtonPushed, true);
            app.TinhvaVeButton.Text = 'Vẽ đồ thị';
            app.daucongLabel = uilabel(app.UIFigure);
            app.daucongLabel.Text = '+';
            app.yLabel = uilabel(app.UIFigure);
            app.yLabel.Text = 'y =';
            app.KQptrA1 = uilabel(app.UIFigure);
            app.KQptrA1.Text = '0';
            app.xLabel = uilabel(app.UIFigure);
            app.xLabel.Text = 'x';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = giaodienhoiquy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end