classdef noisuy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        NisuyTab                    matlab.ui.container.Tab
        Phuongphap                  matlab.ui.control.DropDown
        PhuongphapDropDownLabel     matlab.ui.control.Label
        Nghiem                      matlab.ui.control.TextArea
        NghimTextAreaLabel          matlab.ui.control.Label
        Find                        matlab.ui.control.Button
        XO                          matlab.ui.control.NumericEditField
        NhpgitrnisuyEditFieldLabel  matlab.ui.control.Label
        Draw                        matlab.ui.control.Button
        Output                      matlab.ui.control.TextArea
        athcnisuyTextAreaLabel      matlab.ui.control.Label
        Y                           matlab.ui.control.EditField
        NhpdliuyEditFieldLabel      matlab.ui.control.Label
        X                           matlab.ui.control.EditField
        NhpdliuxEditFieldLabel      matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
        DanhschnhmTab               matlab.ui.container.Tab
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: X
        function XValueChanged(app, event)

        end

        % Button pushed function: Find
        function FindButtonPushed(app, event)
            %Lay du lieu dau vao
            x = app.X.Value;
            x_number = strsplit(x, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
            xa = str2double(x_number); % Chuyển các phần tử của mảng chuỗi thành số

            y = app.Y.Value;
            y_number = strsplit(y, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
            ya = str2double(y_number); % Chuyển các phần tử của mảng chuỗi thành số
            
            xo = app.XO.Value;
            z = app.Phuongphap.Value;
            if(z == "Lagrange")
                dathuc = ResetLagrange(xa, ya);
                dathuc1 = char(dathuc);
                app.Output.Value = dathuc1;
                %%%%%%%%%%%%%%%%%%%%%%%%%%
                nghiem = Lagrange(xa, ya, xo);
                %nghiem1 = char(nghiem);
                app.Nghiem.Value = string(nghiem);
            else
                dathuc = ResetNewton(xa, ya);
                dathuc1 = char(dathuc);
                app.Output.Value = dathuc1;
                %%%%%%%%%%%%%%%%%%%%%%%%%%%
                nghiem = NewtonInterpolation(xa, ya, xo);
                %nghiem1 = char(nghiem);
                app.Nghiem.Value = string(nghiem);
            end
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%Da thuc lagrange
            function result = ResetLagrange(xa, ya)
                n = length(xa);
                text = 0;
                syms x;
                for i = 1 : 1 : n
                    product = ya(i);
                    for j = 1 : 1 : n
                        if(i ~= j)
                            product = product * (x -xa(j))/(xa(i) - xa(j));
                        end
                    end
                    text = text + product;
                end
                result = simplify(text);
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%Da thuc Newton
            function result = ResetNewton(xa, ya)
                 n = length(xa);
                 d = ya;
                 for i = 1 : 1 : n
                     for j = 1: 1 :(i - 1)
                        d(i) = (d(j)- d(i)) / (xa(j) - xa(i));
                     end
                 end
                     syms x;
                     n = length(d);
                     text = d(n);
                     for i = (n - 1): -1 : 1
                        text = text * (x - xa(i)) + d(i); 
                     end
                 result = simplify(text);
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%Nghiem Lagrange
            function result = Lagrange(xa, ya, x)
                n = length(xa);
                sum = 0;
                for i = 1 : 1 : n
                    product = ya(i);
                    for j = 1 : 1 : n
                        if(i ~= j)
                            product = product * (x - xa(j))/(xa(i) -xa(j));
                        end
                    end
                sum = sum + product;
                end
                result = sum;
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%Nghiem Newton
            function result = NewtonInterpolation(xa, ya, x)
                n = length(xa);
                d = ya;
                for i = 1 : 1 : n
                    for j = 1 : 1 : (i - 1)
                        d(i) = (d(j)- d(i)) / (xa(j) - xa(i));
                    end
                end
                n = length(d);
                result = d(n);
                for i = (n - 1) : -1 : 1
                    result = result * (x - xa(i)) + d(i);
                end
            end
        end

        % Button pushed function: Draw
        function DrawButtonPushed(app, event)
            x = app.X.Value;
            x_number = strsplit(x, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
            xa = str2double(x_number); % Chuyển các phần tử của mảng chuỗi thành số

            y = app.Y.Value;
            y_number = strsplit(y, ','); % Chia chuỗi thành các phần tử bằng dấu phẩy và khoảng trắng
            ya = str2double(y_number); % Chuyển các phần tử của mảng chuỗi thành số
            
            %xo = app.XO.Value;
            plot(app.UIAxes, xa, ya);
        end

        % Value changed function: Phuongphap
        function PhuongphapValueChanged(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxes = uiaxes(app.NisuyTab);
            title(app.UIAxes, 'Hàm nội suy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NhpdliuxEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.X = uieditfield(app.NisuyTab, 'text');
            app.X.ValueChangedFcn = createCallbackFcn(app, @XValueChanged, true);
            app.NhpdliuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.Y = uieditfield(app.NisuyTab, 'text');
            app.athcnisuyTextAreaLabel = uilabel(app.NisuyTab);
            app.athcnisuyTextAreaLabel.Text = 'Đa thức nội suy';
            app.Output = uitextarea(app.NisuyTab);
            app.Draw = uibutton(app.NisuyTab, 'push');
            app.Draw.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.Draw.Text = 'Vẽ đồ thị';
            app.NhpgitrnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.NhpgitrnisuyEditFieldLabel.Text = 'Nhập giá trị nội suy';
            app.XO = uieditfield(app.NisuyTab, 'numeric');
            app.Find = uibutton(app.NisuyTab, 'push');
            app.Find.ButtonPushedFcn = createCallbackFcn(app, @FindButtonPushed, true);
            app.Find.Text = {'Tìm nghiệm và ĐTNS'; ''};
            app.NghimTextAreaLabel = uilabel(app.NisuyTab);
            app.NghimTextAreaLabel.Text = 'Nghiệm';
            app.Nghiem = uitextarea(app.NisuyTab);
            app.PhuongphapDropDownLabel = uilabel(app.NisuyTab);
            app.PhuongphapDropDownLabel.Text = 'Phuong phap';
            app.Phuongphap = uidropdown(app.NisuyTab);
            app.Phuongphap.Items = {'Lagrange', 'Newton', ''};
            app.Phuongphap.ValueChangedFcn = createCallbackFcn(app, @PhuongphapValueChanged, true);
            app.Phuongphap.Value = 'Lagrange';
            app.DanhschnhmTab = uitab(app.TabGroup);
            app.DanhschnhmTab.Title = 'Danh sách nhóm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = noisuy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end